classdef Task3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        result_field                matlab.ui.control.NumericEditField
        error_field                 matlab.ui.control.EditField
        operator1                   matlab.ui.control.EditField
        EditField_15                matlab.ui.control.EditField
        EditField_14                matlab.ui.control.EditField
        EditField_13                matlab.ui.control.EditField
        DeleteButton                matlab.ui.control.Button
        EnterButton                 matlab.ui.control.Button
        EditField_10                matlab.ui.control.EditField
        ErrorconsoleLabel_2         matlab.ui.control.Label
        ListBox_3                   matlab.ui.control.ListBox
        EditField_9                 matlab.ui.control.EditField
        EqualButton                 matlab.ui.control.Button
        PlottingoptionsButtonGroup  matlab.ui.container.ButtonGroup
        equationButton              matlab.ui.control.RadioButton
        cosineButton                matlab.ui.control.RadioButton
        sineButton                  matlab.ui.control.RadioButton
        resultantButton             matlab.ui.control.RadioButton
        xyaxisButton                matlab.ui.control.RadioButton
        yaxisButton                 matlab.ui.control.RadioButton
        xaxisButton                 matlab.ui.control.RadioButton
        resultLabel                 matlab.ui.control.Label
        parameter2                  matlab.ui.control.NumericEditField
        parameter1                  matlab.ui.control.NumericEditField
        XAxisLabel                  matlab.ui.control.Label
        YAxisLabel                  matlab.ui.control.Label
        EditField_5                 matlab.ui.control.EditField
        EnterEquationfunxoryxLabel  matlab.ui.control.Label
        Label_2                     matlab.ui.control.Label
        CalculateButton             matlab.ui.control.Button
        listOperations1             matlab.ui.control.ListBox
        ArithmaticOperationsLabel   matlab.ui.control.Label
        ErrorconsoleLabel           matlab.ui.control.Label
        SimpleCalculatorLabel       matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, arg1)
          
            global result;
            global Error;
            result=0;
            Error='No error';
        end

        % Value changed function: parameter1
        function parameter1ValueChanged(app, event)
            
        end

        % Value changed function: parameter2
        function parameter2ValueChanged(app, event)
          
            
        end

        % Callback function
        function operator1ValueChanged(app, event)

            
        end

        % Callback function
        function result1ValueChanged(app, event)
            
        end

        % Value changed function: listOperations1
        function listOperations1ValueChanged(app, event)
           
            
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
         local_result=0;
         app.operator1.Value=app.listOperations1.Value;
         local_operator=app.operator1.Value;
         if(local_operator=='+')
             local_result=app.parameter1.Value+app.parameter2.Value;
              app.result_field.Value=local_result;
              
              app.error_field.Value='No error';
         elseif(local_operator=='-')
            local_result= app.parameter1.Value-app.parameter2.Value;
             app.result_field.Value=local_result;

             app.error_field.Value='No error';
         elseif(local_operator=='/')
             if(app.parameter2.Value==0)
                 app.error_field.Value='Can not divide by Zero';
                 app.result_field.Value=0;
             else
                 local_result=app.parameter1.Value/app.parameter2.Value;
                  app.result_field.Value=local_result;
    
                  app.error_field.Value='No error';
             end
         elseif(local_operator=='*')
             local_result=app.parameter1.Value*app.parameter2.Value;
              app.result_field.Value=local_result;

              app.error_field.Value='No error';
         else
             app.error_field.Value='Error operator wrong';
         end

        end

        % Callback function
        function Error_fieldValueChanged(app, event)
                    
        end

        % Value changed function: operator1
        function operator1ValueChanged2(app, event)
            
        end

        % Value changing function: error_field
        function error_fieldValueChanging(app, event)
            
        end

        % Value changed function: error_field
        function error_fieldValueChanged(app, event)
            value = app.error_field.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Plot area')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.SimpleCalculatorLabel = uilabel(app.UIFigure);
            app.SimpleCalculatorLabel.Text = 'Simple Calculator';
            app.ErrorconsoleLabel = uilabel(app.UIFigure);
            app.ErrorconsoleLabel.Text = 'Error console';
            app.ArithmaticOperationsLabel = uilabel(app.UIFigure);
            app.ArithmaticOperationsLabel.Text = 'Arithmatic Operations';
            app.listOperations1 = uilistbox(app.UIFigure);
            app.listOperations1.Items = {'+', '-', '*', '/'};
            app.listOperations1.ValueChangedFcn = createCallbackFcn(app, @listOperations1ValueChanged, true);
            app.listOperations1.Value = '+';
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate!';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '=';
            app.EnterEquationfunxoryxLabel = uilabel(app.UIFigure);
            app.EnterEquationfunxoryxLabel.Text = 'Enter Equation fun(x) or y(x)';
            app.EditField_5 = uieditfield(app.UIFigure, 'text');
            app.YAxisLabel = uilabel(app.UIFigure);
            app.YAxisLabel.Text = 'Y Axis ';
            app.XAxisLabel = uilabel(app.UIFigure);
            app.XAxisLabel.Text = 'X Axis';
            app.parameter1 = uieditfield(app.UIFigure, 'numeric');
            app.parameter1.ValueChangedFcn = createCallbackFcn(app, @parameter1ValueChanged, true);
            app.parameter2 = uieditfield(app.UIFigure, 'numeric');
            app.parameter2.ValueChangedFcn = createCallbackFcn(app, @parameter2ValueChanged, true);
            app.resultLabel = uilabel(app.UIFigure);
            app.resultLabel.Text = 'result ';
            app.PlottingoptionsButtonGroup = uibuttongroup(app.UIFigure);
            app.PlottingoptionsButtonGroup.Title = 'Plotting options';
            app.xaxisButton = uiradiobutton(app.PlottingoptionsButtonGroup);
            app.xaxisButton.Text = 'x axis';
            app.xaxisButton.Value = true;
            app.yaxisButton = uiradiobutton(app.PlottingoptionsButtonGroup);
            app.yaxisButton.Text = 'y axis';
            app.xyaxisButton = uiradiobutton(app.PlottingoptionsButtonGroup);
            app.xyaxisButton.Text = 'xy axis';
            app.resultantButton = uiradiobutton(app.PlottingoptionsButtonGroup);
            app.resultantButton.Text = 'resultant';
            app.sineButton = uiradiobutton(app.PlottingoptionsButtonGroup);
            app.sineButton.Text = 'sine';
            app.cosineButton = uiradiobutton(app.PlottingoptionsButtonGroup);
            app.cosineButton.Text = 'cosine';
            app.equationButton = uiradiobutton(app.PlottingoptionsButtonGroup);
            app.equationButton.Text = 'equation';
            app.EqualButton = uibutton(app.UIFigure, 'push');
            app.EqualButton.Text = 'Equal';
            app.EditField_9 = uieditfield(app.UIFigure, 'text');
            app.ListBox_3 = uilistbox(app.UIFigure);
            app.ListBox_3.Items = {'+', '-', '*', '/'};
            app.ListBox_3.Value = '+';
            app.ErrorconsoleLabel_2 = uilabel(app.UIFigure);
            app.ErrorconsoleLabel_2.Text = 'Error console';
            app.EditField_10 = uieditfield(app.UIFigure, 'text');
            app.EnterButton = uibutton(app.UIFigure, 'push');
            app.EnterButton.Text = 'Enter';
            app.DeleteButton = uibutton(app.UIFigure, 'push');
            app.DeleteButton.Text = 'Delete';
            app.EditField_13 = uieditfield(app.UIFigure, 'text');
            app.EditField_14 = uieditfield(app.UIFigure, 'text');
            app.EditField_15 = uieditfield(app.UIFigure, 'text');
            app.operator1 = uieditfield(app.UIFigure, 'text');
            app.operator1.ValueChangedFcn = createCallbackFcn(app, @operator1ValueChanged2, true);
            app.error_field = uieditfield(app.UIFigure, 'text');
            app.error_field.ValueChangedFcn = createCallbackFcn(app, @error_fieldValueChanged, true);
            app.error_field.ValueChangingFcn = createCallbackFcn(app, @error_fieldValueChanging, true);
            app.result_field = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Task3(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end