classdef task2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Below60HappyAbove60SadLabel  matlab.ui.control.Label
        EntervalueEditField          matlab.ui.control.NumericEditField
        EntervalueEditFieldLabel     matlab.ui.control.Label
        GetImageButton               matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, button, axes)
            global value;
            global image_sad;
            global image_happy;
            value=0;
            image_sad=imread("sad.png");
            image_happy=imread("happy.png");
            imshow("neutral.png",'Parent',app.UIAxes);
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
                
        end

        % Button pushed function: GetImageButton
        function GetImageButtonPushed(app, event)
            global value;
            global image_sad;
            global image_happy;
            if(value<60)
                imshow(image_happy,'Parent',app.UIAxes);
            else
                imshow(image_sad,'Parent',app.UIAxes);
            end
        end

        % Value changed function: EntervalueEditField
        function EntervalueEditFieldValueChanged(app, event)
            global value;
            value = app.EntervalueEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Status of Happiness')
            app.UIAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.GetImageButton = uibutton(app.UIFigure, 'push');
            app.GetImageButton.ButtonPushedFcn = createCallbackFcn(app, @GetImageButtonPushed, true);
            app.GetImageButton.Text = 'Get Image';
            app.EntervalueEditFieldLabel = uilabel(app.UIFigure);
            app.EntervalueEditFieldLabel.Text = 'Enter value';
            app.EntervalueEditField = uieditfield(app.UIFigure, 'numeric');
            app.EntervalueEditField.ValueChangedFcn = createCallbackFcn(app, @EntervalueEditFieldValueChanged, true);
            app.Below60HappyAbove60SadLabel = uilabel(app.UIFigure);
            app.Below60HappyAbove60SadLabel.Text = {'Below 60% : Happy :)'; 'Above 60% : Sad :('};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = task2(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end